classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        Button6                       ccTools.Button
        ccToolsTableLabel             matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        FontColorDropDown             matlab.ui.control.DropDown
        FontColorDropDownLabel        matlab.ui.control.Label
        FontWeightDropDown            matlab.ui.control.DropDown
        FontWeightDropDownLabel       matlab.ui.control.Label
        FontSizeDropDown              matlab.ui.control.DropDown
        FontSizeDropDownLabel         matlab.ui.control.Label
        FontNameDropDown              matlab.ui.control.DropDown
        FontNameDropDownLabel         matlab.ui.control.Label
        BackgroundColorDropDown       matlab.ui.control.DropDown
        BackgroundColorDropDownLabel  matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        Slider                        matlab.ui.control.Slider
        HeadercustomizationCheckBox   matlab.ui.control.CheckBox
        Panel_2                       matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        Button5                       ccTools.Button
        Button4                       ccTools.Button
        Button3                       ccTools.Button
        Button2                       ccTools.Button
        Button1                       ccTools.Button
        ccToolsButtonLabel            matlab.ui.control.Label
        ccToolscompCustomizationMatlabbuiltincomponentsLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        ccTable                       ccTools.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button1, Button2, Button3, Button4, Button5
        function ButtonPushed(app, event)
            
            switch event.Source
                case app.Button1
                    ccTools.MessageBox(app.UIFigure, '<font style="font-size: 16px;">ccTools</font><br>MessageBox', 'winBackgroundColor', '#a2142f', 'msgFontColor', 'white')

                case app.Button2
                    d = ccTools.ProgressDialog(app.UIFigure, 'color', '#a2142f');
                    pause(3)
                    delete(d)

                case app.Button3
                    d = ccTools.ProgressDialog(app.UITable);
                    pause(3)
                    delete(d)

                case app.Button4
                    if strcmp(app.Button4.BorderColor, '#808080')
                        set(app.Button4, 'BorderColor', '#a2142f', 'tFontColor', '#a2142f')
                    else
                        set(app.Button4, 'BorderColor', '#808080', 'tFontColor', 'black')
                    end

                case app.Button5
                    if strcmp(app.Button5.tFontColor, 'white')
                        set(app.Button5, 'BackgroundColor', [0.80,0.80,0.80], 'tFontColor', 'black', 'BorderRadius', '25%', 'BorderWidth', 2, 'BorderColor', 'black')
                    else
                        set(app.Button5, 'BackgroundColor', [0.32,0.33,0.36], 'tFontColor', 'white', 'BorderRadius', '50%', 'BorderWidth', 0)
                    end
            end

        end

        % Value changed function: HeadercustomizationCheckBox
        function CheckBoxValueChanged(app, event)
            
            if app.HeadercustomizationCheckBox.Value
                set(app.GridLayout2.Children, 'Enable', 1)
                UITableHeaderCustomization(app)
            else
                set(app.GridLayout2.Children, 'Enable', 0)
            end

        end

        % Value changed function: BackgroundColorDropDown, 
        % ...and 4 other components
        function UITableHeaderCustomization(app, event)

            ccTools.compCustomization(app.UITable, 'backgroundHeaderColor', app.BackgroundColorDropDown.Value, ...
                                                   'fontFamily', app.FontNameDropDown.Value, ...
                                                   'fontSize', app.FontSizeDropDown.Value, ...
                                                   'fontWeight', app.FontWeightDropDown.Value, ...
                                                   'color', app.FontColorDropDown.Value)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
            ccTools.compCustomization(app.UITable, 'borderRadius', sprintf('%.0fpx', event.Value));
            
        end

        % Callback function: Button6
        function Button6Pushed(app, event)
            
            [fName, fPath] = uigetfile('*.xlsx');
            figure(app.UIFigure)
            
            try
                tempTable = readtable(fullfile(fPath, fName));
                app.ccTable.Data = tempTable;
                app.UITable.Data = tempTable;
                
            catch ME
                ccTools.MessageBox(app.UIFigure, getReport(ME), 'winWidth', '440px')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ccTable = ccTools.Table(app.GridLayout);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ccToolscompCustomizationMatlabbuiltincomponentsLabel = uilabel(app.GridLayout);
            app.ccToolscompCustomizationMatlabbuiltincomponentsLabel.Text = 'ccTools.compCustomization (Matlab built-in components)';
            app.ccToolsButtonLabel = uilabel(app.GridLayout);
            app.ccToolsButtonLabel.Text = 'ccTools.Button';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.Button1 = ccTools.Button(app.GridLayout3);
            app.Button1.Text = 'ccTools.MessageBox';
            app.Button1.Description = 'Open modal element';
            app.Button1.tFontSize = 14;
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = ccTools.Button(app.GridLayout3);
            app.Button2.Text = 'ccTools.ProgressDialog';
            app.Button2.Description = 'Open modal element above uifigure';
            app.Button2.tFontSize = 14;
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button3 = ccTools.Button(app.GridLayout3);
            app.Button3.Text = 'Progress dialog';
            app.Button3.Description = 'Open modal element above uitable';
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button4 = ccTools.Button(app.GridLayout3);
            app.Button4.Text = 'Btn';
            app.Button4.Description = '';
            app.Button4.HorizontalAlign = 'center';
            app.Button4.BorderRadius = '20px';
            app.Button4.BorderPadding = 0;
            app.Button4.IconWidth = 24;
            app.Button4.IconHeight = 24;
            app.Button4.tFontSize = 16;
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button5 = ccTools.Button(app.GridLayout3);
            app.Button5.Model = 'Text';
            app.Button5.Text = 'Btn';
            app.Button5.Description = '';
            app.Button5.HorizontalAlign = 'center';
            app.Button5.BorderRadius = '50%';
            app.Button5.tFontSize = 28;
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.HeadercustomizationCheckBox = uicheckbox(app.GridLayout4);
            app.HeadercustomizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.HeadercustomizationCheckBox.Text = 'Header customization';
            app.Slider = uislider(app.GridLayout4);
            app.Slider.Limits = [0 20];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.BackgroundColorDropDownLabel = uilabel(app.GridLayout2);
            app.BackgroundColorDropDownLabel.Enable = 'off';
            app.BackgroundColorDropDownLabel.Text = 'BackgroundColor';
            app.BackgroundColorDropDown = uidropdown(app.GridLayout2);
            app.BackgroundColorDropDown.Items = {'#52555C', '#808080', '#F0F0F0', '#009578', '#CE644A', '#A2142F'};
            app.BackgroundColorDropDown.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.BackgroundColorDropDown.Enable = 'off';
            app.BackgroundColorDropDown.Value = '#009578';
            app.FontNameDropDownLabel = uilabel(app.GridLayout2);
            app.FontNameDropDownLabel.Enable = 'off';
            app.FontNameDropDownLabel.Text = 'FontName';
            app.FontNameDropDown = uidropdown(app.GridLayout2);
            app.FontNameDropDown.Items = {'Courier', 'Helvetica', 'Symbol', 'Times New Roman'};
            app.FontNameDropDown.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.FontNameDropDown.Enable = 'off';
            app.FontNameDropDown.Value = 'Times New Roman';
            app.FontSizeDropDownLabel = uilabel(app.GridLayout2);
            app.FontSizeDropDownLabel.Enable = 'off';
            app.FontSizeDropDownLabel.Text = 'FontSize';
            app.FontSizeDropDown = uidropdown(app.GridLayout2);
            app.FontSizeDropDown.Items = {'8px', '10px', '12px', '14px'};
            app.FontSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.FontSizeDropDown.Enable = 'off';
            app.FontSizeDropDown.Value = '12px';
            app.FontWeightDropDownLabel = uilabel(app.GridLayout2);
            app.FontWeightDropDownLabel.Enable = 'off';
            app.FontWeightDropDownLabel.Text = 'FontWeight';
            app.FontWeightDropDown = uidropdown(app.GridLayout2);
            app.FontWeightDropDown.Items = {'normal', 'bold'};
            app.FontWeightDropDown.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.FontWeightDropDown.Enable = 'off';
            app.FontWeightDropDown.Value = 'bold';
            app.FontColorDropDownLabel = uilabel(app.GridLayout2);
            app.FontColorDropDownLabel.Enable = 'off';
            app.FontColorDropDownLabel.Text = 'FontColor';
            app.FontColorDropDown = uidropdown(app.GridLayout2);
            app.FontColorDropDown.Items = {'white', 'black'};
            app.FontColorDropDown.ValueChangedFcn = createCallbackFcn(app, @UITableHeaderCustomization, true);
            app.FontColorDropDown.Enable = 'off';
            app.FontColorDropDown.Value = 'white';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.ccToolsTableLabel = uilabel(app.GridLayout5);
            app.ccToolsTableLabel.Text = 'ccTools.Table';
            app.Button6 = ccTools.Button(app.GridLayout5);
            app.Button6.Model = 'Icon';
            app.Button6.HorizontalAlign = 'center';
            app.Button6.BorderRadius = '0px';
            app.Button6.BorderPadding = 1;
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end